classdef array_angle_control_GUI_IK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label_2                  matlab.ui.control.Label
        JointanglesdegreesLabel  matlab.ui.control.Label
        GrasperSliderLabel_4     matlab.ui.control.Label
        GrasperSliderLabel_3     matlab.ui.control.Label
        GrasperSliderLabel_2     matlab.ui.control.Label
        ee_val                   matlab.ui.control.Label
        z_val                    matlab.ui.control.Label
        y_val                    matlab.ui.control.Label
        x_val                    matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        COMPortEditField         matlab.ui.control.EditField
        COMPortLabel             matlab.ui.control.Label
        GoHomeButton             matlab.ui.control.Button
        J3_val                   matlab.ui.control.Label
        J2_val                   matlab.ui.control.Label
        J1_val                   matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        GrasperSlider            matlab.ui.control.Slider
        GrasperSliderLabel       matlab.ui.control.Label
        ZmmSlider                matlab.ui.control.Slider
        ZmmSliderLabel           matlab.ui.control.Label
        YmmSlider                matlab.ui.control.Slider
        YmmSliderLabel           matlab.ui.control.Label
        XmmSlider                matlab.ui.control.Slider
        XmmSliderLabel           matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduinoObj % Description

        joint_val
        
        x = 255;
        y = 0;
        z = 110;
        
        %%% define link lengths
        d1 = 110;
        l2 = 105;
        l3 = 150;

        %%% define joint limits in degrees %%%
        j1_limits = [-90 90];
        j2_limits = [0 125];
        j3_limits = [0 135];
        
        errorStatus = 1;

        status_vals = {"Error Status: Nominal", ...
                           "Error Status: Outter workspace boundary limit",... 
                           "Error Status: Inner workspace boundary limit",...
                           "Error Status: Singularity"}

        status_color = ['k', 'r', 'r', 'r']

        r_plot
        r_plot_2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.COMPortEditField.Value = "COM7";
            
            %% plot workspace
            %%% point #1 %%
            % val_min at j3 limit (law of cosines)
            val_min = sqrt(app.l2^2 + app.l3^2 - 2*app.l2*app.l3*cosd(180-app.j3_limits(2)));
            
            xy_min = 70; % measured value
            z_min = sqrt(val_min^2 - xy_min^2);
            z_min_2 = app.d1 - z_min;
            
            plot(app.UIAxes,[70 70],[0 z_min_2], 'k', 'LineWidth',2);
            hold(app.UIAxes, 'on');
            
            %%% point #2 %%
            xy_val_z0 = sqrt(app.l3^2 - app.d1^2);
            xy_val_z0_2 = app.l2 + xy_val_z0;
            
            plot(app.UIAxes,[xy_min xy_val_z0_2],[0 0], 'k', 'LineWidth',2);
            
            %%% arc #1 %%
            alpha_1 = acos(app.d1/app.l3); % to 180 degrees
            
            r_angl = linspace(0, -pi/2+alpha_1, 25); % Angle Defining Arc Segment (radians)
            radius = app.l3;    
            xy_r = [radius*cos(r_angl);  radius*sin(r_angl)]; % Matrix (2xN) Of (x,y) Coordinates                               
            plot(app.UIAxes,xy_r(1,:) + app.l2, xy_r(2,:) + app.d1, 'k', 'LineWidth',2)  ;
            
            %%% arc #2 %%
            r_angl = linspace(0, app.j2_limits(2)/180*pi, 50); % Angle Defining Arc Segment (radians)
            radius = app.l2+app.l3;    
            xy_r = [radius*cos(r_angl);  radius*sin(r_angl)]; % Matrix (2xN) Of (x,y) Coordinates                               
            plot(app.UIAxes,xy_r(1,:), xy_r(2,:) + app.d1, 'k', 'LineWidth',2)  ;
            
            %%% arc #3 %%
            x_1 = app.l2*sind(app.j2_limits(2)-90);
            y_1 = app.l2*cosd(app.j2_limits(2)-90);
            
            r_angl = linspace(app.j2_limits(2)/180*pi, pi/2-app.j3_limits(2)/180*pi + (app.j2_limits(2)-90)/180*pi, 50); % Angle Defining Arc Segment (radians)
            radius = app.l3;    
            xy_r = [radius*cos(r_angl);  radius*sin(r_angl)]; % Matrix (2xN) Of (x,y) Coordinates                               
            plot(app.UIAxes,xy_r(1,:) - x_1, xy_r(2,:) + app.d1 + y_1, 'k', 'LineWidth',2)  ;
            
            %%% arc #4 %%
            
            alpha_2 = asin(app.l3/val_min*sind(180-app.j3_limits(2)));
            
            r_angl = linspace(app.j2_limits(2)/180*pi - alpha_2, -pi/2 + acos(z_min/val_min), 25); % Angle Defining Arc Segment (radians)
            
            radius = val_min;    
            xy_r = [radius*cos(r_angl);  radius*sin(r_angl)]; % Matrix (2xN) Of (x,y) Coordinates                               
            plot(app.UIAxes,xy_r(1,:), xy_r(2,:) + app.d1, 'k', 'LineWidth',2)  
            
            %%%
            axis(app.UIAxes, 'equal'); % No Distortion With ;axis equal’
            app.UIAxes.Box = 'on';

            %% plot top down view
            %%%% inner arc
            r_angl = linspace(app.j1_limits(1)/180*pi,app.j1_limits(2)/180*pi, 50); % Angle Defining Arc Segment (radians)
            radius = xy_min;    
            xy_r = [radius*cos(r_angl);  radius*sin(r_angl)]; % Matrix (2xN) Of (x,y) Coordinates                               
            plot(app.UIAxes_2, xy_r(2,:), xy_r(1,:), 'k', 'LineWidth',2);
            hold(app.UIAxes_2, 'on');

            %%% lines
            plot(app.UIAxes_2, [xy_min app.l2+app.l3], [0 0], 'k', 'LineWidth',2);
            plot(app.UIAxes_2, [-xy_min -(app.l2+app.l3)], [0 0], 'k', 'LineWidth',2);
            
            %%%% outer arc
            r_angl = linspace(app.j1_limits(1)/180*pi,app.j1_limits(2)/180*pi, 50); % Angle Defining Arc Segment (radians)
            radius = app.l2+app.l3;    
            xy_r = [radius*cos(r_angl);  radius*sin(r_angl)]; % Matrix (2xN) Of (x,y) Coordinates                               
            plot(app.UIAxes_2, xy_r(2,:), xy_r(1,:), 'k', 'LineWidth',2);

            %%%
            axis(app.UIAxes_2, 'equal'); % No Distortion With _axis equal2
            app.UIAxes_2.Box = 'on';
            view(app.UIAxes_2, [180 -90]);
        end

        % Value changed function: XmmSlider
        function XmmSliderValueChanged(app, event)
            app.x = round(app.XmmSlider.Value); % Get 
            app.x_val.Text = num2str(app.x); % update text
            
            % Inv Kin
            [app.joint_val(1), app.joint_val(2), app.joint_val(3), app.errorStatus]  = inv_kin(app.x, app.y, app.z, app.d1, app.l2, app.l3);
                        
            % update joint values
            app.J1_val.Text = num2str(app.joint_val(1));
            app.J2_val.Text = num2str(app.joint_val(2));
            app.J3_val.Text = num2str(app.joint_val(3));
            
            % update error status
            app.Label_2.Text = app.status_vals{app.errorStatus};
            app.Label_2.FontColor = app.status_color(app.errorStatus);
            
            %%% plot robot
            delete(app.r_plot); % delete old robot
            
            xy1 = app.l2*cosd(app.joint_val(2));
            z1 = app.l2*sind(app.joint_val(2)) + app.d1;
            
            xy2 = sqrt(app.x^2+app.y^2);
            z2 = app.z;

            app.r_plot(1) = plot(app.UIAxes,[0 xy1],[110 z1], 'r', 'LineWidth',3) ;
            app.r_plot(2) = plot(app.UIAxes,[xy1 xy2],[z1 z2], 'r', 'LineWidth',3) ;
            app.r_plot(3) = plot(app.UIAxes,[0 xy1 xy2],[110 z1 z2],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % robot 2
            delete(app.r_plot_2);
            x1 = app.l2*cosd(app.joint_val(2))*cosd(app.joint_val(1));
            y1 = app.l2*cosd(app.joint_val(2))*sind(app.joint_val(1));

            x2 = app.x;
            y2 = app.y;

            app.r_plot_2(1) = plot(app.UIAxes_2,[0 y1],[0 x1], 'r', 'LineWidth',3);
            app.r_plot_2(2) = plot(app.UIAxes_2,[y1 y2],[x1 x2], 'r', 'LineWidth',3);
            app.r_plot_2(3) = plot(app.UIAxes_2,[0 y1 y2],[0 x1 x2],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % send to Arduino
            sendString = encodeString(app.joint_val);
            app.arduinoObj.writeline(sendString);
        end

        % Callback function
        function ConnecttoArduinoButtonPushed(app, event)

        end

        % Value changed function: YmmSlider
        function YmmSliderValueChanged(app, event)
            app.y = round(app.YmmSlider.Value); % Get 
            app.y_val.Text = num2str(app.y); % update text
            
            % Inv Kin
            [app.joint_val(1), app.joint_val(2), app.joint_val(3), app.errorStatus]  = inv_kin(app.x, app.y, app.z, app.d1, app.l2, app.l3);
                        
            % update joint values
            app.J1_val.Text = num2str(app.joint_val(1));
            app.J2_val.Text = num2str(app.joint_val(2));
            app.J3_val.Text = num2str(app.joint_val(3));
            
            % update error status
            app.Label_2.Text = app.status_vals{app.errorStatus};
            app.Label_2.FontColor = app.status_color(app.errorStatus);
            
            %%% plot robot
            delete(app.r_plot); % delete old robot
            
            xy1 = app.l2*cosd(app.joint_val(2));
            z1 = app.l2*sind(app.joint_val(2)) + app.d1;
            
            xy2 = sqrt(app.x^2+app.y^2);
            z2 = app.z;

            app.r_plot(1) = plot(app.UIAxes,[0 xy1],[110 z1], 'r', 'LineWidth',3) ;
            app.r_plot(2) = plot(app.UIAxes,[xy1 xy2],[z1 z2], 'r', 'LineWidth',3) ;
            app.r_plot(3) = plot(app.UIAxes,[0 xy1 xy2],[110 z1 z2],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % robot 2
            delete(app.r_plot_2);
            x1 = app.l2*cosd(app.joint_val(2))*cosd(app.joint_val(1));
            y1 = app.l2*cosd(app.joint_val(2))*sind(app.joint_val(1));

            x2 = app.x;
            y2 = app.y;

            app.r_plot_2(1) = plot(app.UIAxes_2,[0 y1],[0 x1], 'r', 'LineWidth',3) ;
            app.r_plot_2(2) = plot(app.UIAxes_2,[y1 y2],[x1 x2], 'r', 'LineWidth',3) ;
            app.r_plot_2(3) = plot(app.UIAxes_2,[0 y1 y2],[0 x1 x2],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % send to Arduino
            sendString = encodeString(app.joint_val);
            app.arduinoObj.writeline(sendString);
        end

        % Value changed function: ZmmSlider
        function ZmmSliderValueChanged(app, event)
            app.z = round(app.ZmmSlider.Value); % Get 
            app.z_val.Text = num2str(app.z); % update text
            
            % Inv Kin
            [app.joint_val(1), app.joint_val(2), app.joint_val(3), app.errorStatus]  = inv_kin(app.x, app.y, app.z, app.d1, app.l2, app.l3);
                        
            % update joint values
            app.J1_val.Text = num2str(app.joint_val(1));
            app.J2_val.Text = num2str(app.joint_val(2));
            app.J3_val.Text = num2str(app.joint_val(3));
            
            % update error status
            app.Label_2.Text = app.status_vals{app.errorStatus};
            app.Label_2.FontColor = app.status_color(app.errorStatus);
            
            %%% plot robot
            delete(app.r_plot); % delete old robot
            
            xy1 = app.l2*cosd(app.joint_val(2));
            z1 = app.l2*sind(app.joint_val(2)) + app.d1;
            
            xy2 = sqrt(app.x^2+app.y^2);
            z2 = app.z;

            app.r_plot(1) = plot(app.UIAxes,[0 xy1],[110 z1], 'r', 'LineWidth',3) ;
            app.r_plot(2) = plot(app.UIAxes,[xy1 xy2],[z1 z2], 'r', 'LineWidth',3) ;
            app.r_plot(3) = plot(app.UIAxes,[0 xy1 xy2],[110 z1 z2],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % robot 2
            delete(app.r_plot_2);
            x1 = app.l2*cosd(app.joint_val(2))*cosd(app.joint_val(1));
            y1 = app.l2*cosd(app.joint_val(2))*sind(app.joint_val(1));

            x2 = app.x;
            y2 = app.y;

            app.r_plot_2(1) = plot(app.UIAxes_2,[0 y1],[0 x1], 'r', 'LineWidth',3) ;
            app.r_plot_2(2) = plot(app.UIAxes_2,[y1 y2],[x1 x2], 'r', 'LineWidth',3) ;
            app.r_plot_2(3) = plot(app.UIAxes_2,[0 y1 y2],[0 x1 x2],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % send to Arduino
            sendString = encodeString(app.joint_val);
            app.arduinoObj.writeline(sendString);
        end

        % Value changed function: GrasperSlider
        function GrasperSliderValueChanged(app, event)
            app.joint_val(4) = round(app.GrasperSlider.Value); %Get            
            app.ee_val.Text = num2str(app.joint_val(4));

            sendString = encodeString(app.joint_val);
            app.arduinoObj.writeline(sendString);
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            % set safe/home position - configure this if needed
            app.joint_val = [000 000 000 026]; 
            sendString = encodeString(app.joint_val);
            app.arduinoObj.writeline(sendString);
            
            % update joint angle text
            app.J1_val.Text = num2str(app.joint_val(1));
            app.J2_val.Text = num2str(app.joint_val(2));
            app.J3_val.Text = num2str(app.joint_val(3));
            app.ee_val.Text = num2str(app.joint_val(4));
            
            % update xyz position text
            app.x_val.Text = "255";
            app.y_val.Text = "0";
            app.z_val.Text = "110";
            app.x = 255;
            app.y = 0;
            app.z = 110;
            
            % update slider value
            app.XmmSlider.Value = 255;
            app.YmmSlider.Value = 0;
            app.ZmmSlider.Value = 110;
            app.GrasperSlider.Value = 26;

            %%% plot robot
            delete(app.r_plot); % delete old robot
            
            app.r_plot(1) = plot(app.UIAxes,[0 app.l2],[app.d1 app.d1], 'r', 'LineWidth',3) ;
            app.r_plot(2) = plot(app.UIAxes,[app.l2 app.l2+app.l3],[app.d1 app.d1], 'r', 'LineWidth',3) ;
            app.r_plot(3) = plot(app.UIAxes,[0 app.l2 app.l2+app.l3],[app.d1 app.d1 app.d1],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');

            % robot 2
            delete(app.r_plot_2); % delete old robot

            app.r_plot_2(1) = plot(app.UIAxes_2,[0 0],[0 app.l2], 'r', 'LineWidth',3) ;
            app.r_plot_2(2) = plot(app.UIAxes_2,[0 0],[app.l2 app.l2+app.l3], 'r', 'LineWidth',3) ;
            app.r_plot_2(3) = plot(app.UIAxes_2,[0 0 0],[0 app.l2 app.l2+app.l3],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try 
                app.arduinoObj = serialport(app.COMPortEditField.Value,115200); % no modification
            catch
                app.arduinoObj = serialport(convertCharsToStrings(app.COMPortEditField.Value),115200); % text modified
            end
            
            % set terminator characters
            configureTerminator(app.arduinoObj,"CR/LF");
            
            % remove lingering data in arduino buffer
            flush(app.arduinoObj);

            % set listener function for arduino data
            configureCallback(app.arduinoObj,"terminator",@readData);

            % set safe/home position - configure this if needed
            app.joint_val = [000 000 000 026]; 

            app.J1_val.Text = num2str(app.joint_val(1));
            app.J2_val.Text = num2str(app.joint_val(2));
            app.J3_val.Text = num2str(app.joint_val(3));
            app.ee_val.Text = num2str(app.joint_val(4));

            %%% plot robot
            plot(app.UIAxes,[0 0],[0 app.d1], 'r', 'LineWidth',3) ;
            app.r_plot(1) = plot(app.UIAxes,[0 app.l2],[app.d1 app.d1], 'r', 'LineWidth',3) ;
            app.r_plot(2) = plot(app.UIAxes,[0 app.l2+app.l3],[app.d1 app.d1], 'r', 'LineWidth',3) ;
            app.r_plot(3) = plot(app.UIAxes,[0 app.l2 app.l2+app.l3],[app.d1 app.d1 app.d1],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            app.r_plot_2(1) = plot(app.UIAxes_2,[0 0],[0 app.l2], 'r', 'LineWidth',3) ;
            app.r_plot_2(2) = plot(app.UIAxes_2,[0 0],[app.l2 app.l2+app.l3], 'r', 'LineWidth',3) ;
            app.r_plot_2(3) = plot(app.UIAxes_2,[0 0 0],[0 app.l2 app.l2+app.l3],'.', 'MarkerSize', 25, 'MarkerEdgeColor','g');
            
            % turn position array into string
            sendString = encodeString(app.joint_val);
            app.arduinoObj.writeline(sendString);
            
            dots = '...................';
            app.Label.Text = 'Connecting';
            pause(.5);
            for i = 1:19
                app.Label.Text = ['Connecting' dots(1:i)];
                pause(.5);
            end
            app.Label.Text = 'Connected to Arduino.';
            
            % update error status
            app.Label_2.Text = app.status_vals{app.errorStatus};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Z-XY plane')
            xlabel(app.UIAxes, 'XY-Plane')
            ylabel(app.UIAxes, 'Z-Axis')
            zlabel(app.UIAxes, ' ')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'X-Y plane')
            xlabel(app.UIAxes_2, 'Y-Axis')
            ylabel(app.UIAxes_2, 'X-Axis')
            zlabel(app.UIAxes_2, ' ')
            app.XmmSliderLabel = uilabel(app.UIFigure);
            app.XmmSliderLabel.Text = 'X [mm]';
            app.XmmSlider = uislider(app.UIFigure);
            app.XmmSlider.Limits = [0 255];
            app.XmmSlider.ValueChangedFcn = createCallbackFcn(app, @XmmSliderValueChanged, true);
            app.XmmSlider.Value = 255;
            app.YmmSliderLabel = uilabel(app.UIFigure);
            app.YmmSliderLabel.Text = 'Y [mm]';
            app.YmmSlider = uislider(app.UIFigure);
            app.YmmSlider.Limits = [-255 255];
            app.YmmSlider.ValueChangedFcn = createCallbackFcn(app, @YmmSliderValueChanged, true);
            app.ZmmSliderLabel = uilabel(app.UIFigure);
            app.ZmmSliderLabel.Text = 'Z [mm]';
            app.ZmmSlider = uislider(app.UIFigure);
            app.ZmmSlider.Limits = [0 365];
            app.ZmmSlider.ValueChangedFcn = createCallbackFcn(app, @ZmmSliderValueChanged, true);
            app.ZmmSlider.Value = 110;
            app.GrasperSliderLabel = uilabel(app.UIFigure);
            app.GrasperSliderLabel.Text = 'Grasper';
            app.GrasperSlider = uislider(app.UIFigure);
            app.GrasperSlider.Limits = [0 53];
            app.GrasperSlider.ValueChangedFcn = createCallbackFcn(app, @GrasperSliderValueChanged, true);
            app.GrasperSlider.Value = 26;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.J1_val = uilabel(app.UIFigure);
            app.J1_val.Text = '';
            app.J2_val = uilabel(app.UIFigure);
            app.J2_val.Text = '';
            app.J3_val = uilabel(app.UIFigure);
            app.J3_val.Text = '';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.COMPortLabel = uilabel(app.UIFigure);
            app.COMPortLabel.Text = 'COM Port:';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.x_val = uilabel(app.UIFigure);
            app.x_val.Text = '';
            app.y_val = uilabel(app.UIFigure);
            app.y_val.Text = '';
            app.z_val = uilabel(app.UIFigure);
            app.z_val.Text = '';
            app.ee_val = uilabel(app.UIFigure);
            app.ee_val.Text = '';
            app.GrasperSliderLabel_2 = uilabel(app.UIFigure);
            app.GrasperSliderLabel_2.Text = 'Joint 1:';
            app.GrasperSliderLabel_3 = uilabel(app.UIFigure);
            app.GrasperSliderLabel_3.Text = 'Joint 2:';
            app.GrasperSliderLabel_4 = uilabel(app.UIFigure);
            app.GrasperSliderLabel_4.Text = 'Joint 3:';
            app.JointanglesdegreesLabel = uilabel(app.UIFigure);
            app.JointanglesdegreesLabel.Text = 'Joint angles [degrees]';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = array_angle_control_GUI_IK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end